classdef FieldClusterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ClusterFieldsTab                matlab.ui.container.Tab
        LoadingdataPanel                matlab.ui.container.Panel
        TextArea                        matlab.ui.control.TextArea
        LoadDataButton                  matlab.ui.control.Button
        ClusteringPanel                 matlab.ui.container.Panel
        ClusteringstateLamp             matlab.ui.control.Lamp
        RunclusteringButton             matlab.ui.control.Button
        MaxiterationEditField           matlab.ui.control.NumericEditField
        MaxiterationEditFieldLabel      matlab.ui.control.Label
        FieldCutoffEditField            matlab.ui.control.NumericEditField
        FieldCutoffEditFieldLabel       matlab.ui.control.Label
        MinpeakprominenceEditField      matlab.ui.control.NumericEditField
        MinpeakprominenceEditFieldLabel  matlab.ui.control.Label
        SelectAlgorithmButtonGroup      matlab.ui.container.ButtonGroup
        KmeansButton                    matlab.ui.control.RadioButton
        GMMButton                       matlab.ui.control.RadioButton
        ClusteringResultsPanel          matlab.ui.container.Panel
        ViewFieldsTab                   matlab.ui.container.Tab
        SaveResultsButton               matlab.ui.control.Button
        FieldViewerPanel                matlab.ui.container.Panel
        PlottingPanel                   matlab.ui.container.Panel
        ColormapDropDown                matlab.ui.control.DropDown
        ColormapDropDownLabel           matlab.ui.control.Label
        PlotMapsButton                  matlab.ui.control.Button
        CellIDSpinner                   matlab.ui.control.Spinner
        CellIDSpinnerLabel              matlab.ui.control.Label
        PlotpdfGMMonlyCheckBox          matlab.ui.control.CheckBox
        PlotSessionsCheckBox            matlab.ui.control.CheckBox
        ManualeditingPanel              matlab.ui.container.Panel
        DiscardButton                   matlab.ui.control.Button
        SplitButton                     matlab.ui.control.Button
        MergeButton                     matlab.ui.control.Button
        UndoButton                      matlab.ui.control.Button
        FieldselectionListBox           matlab.ui.control.ListBox
        FieldselectionListBoxLabel      matlab.ui.control.Label
        ClusteringPanel_2               matlab.ui.container.Panel
        GMMconvergenceEditField_2       matlab.ui.control.EditField
        GMMconvergenceEditFieldLabel_2  matlab.ui.control.Label
        ClusteringstateLamp_2           matlab.ui.control.Lamp
        RunclusteringButton_2           matlab.ui.control.Button
        MaxiterationEditField_2         matlab.ui.control.NumericEditField
        MaxiterationEditField_2Label    matlab.ui.control.Label
        FieldcutoffEditField            matlab.ui.control.NumericEditField
        FieldcutoffEditFieldLabel       matlab.ui.control.Label
        MinpeakprominenceEditField_2    matlab.ui.control.NumericEditField
        MinpeakprominenceEditField_2Label  matlab.ui.control.Label
        SelectAlgorithmButtonGroup_2    matlab.ui.container.ButtonGroup
        KmeansButton_2                  matlab.ui.control.RadioButton
        GMMButton_2                     matlab.ui.control.RadioButton
        AnalyzeFieldsTab                matlab.ui.container.Tab
        AnalysisViewerPanel             matlab.ui.container.Panel
        AnalysisoptionsPanel            matlab.ui.container.Panel
        RunanalysisButton               matlab.ui.control.Button
        ListBox                         matlab.ui.control.ListBox
    end


    properties (Access = public)
        location; % location where the data lives
        map; % maps to do field clustering on
        map_sess; % maps for individual sessions
        N_cell; % number of cells
        N_session = 1; % number of session
        algorithm = "GMM"; % algorithm type, default is GMM
        peakProm; % peak prominence
        minFieldRate; % mininum rate within field
        maxIter; % maximum iteration of algorithm
        gm; % to store GMM model for each neuron
        IsConverged; % boolean whether GMM converged
        FieldWeight; % component proportion from GMM
        FieldSize; %store field size
        clusters; % to store cluster masks for each neuron
        peakRate; % to store peak rates for each cluster
        peakLoc; % to store peak location for each cluster
        clusterTL; %to plot results of clustering
        fieldTL; %to plot each map
        red = [0.73 0.24 0.15];
        green = [0.26 0.46 0.26];
        cellID; %to store cell IDs
        current; % variable for current cell ID
        field_ax; % axis for fields
        cluster_backup; % to store clusters for preview when merging/splitting 
        mask; % binary version of field maps

    end

    methods (Access = public)

        function SummarizeClustering(app)
            app.clusterTL = tiledlayout(app.ClusteringResultsPanel,2,2);
            ax = nexttile(app.clusterTL);
            NFields= cellfun(@length, app.peakRate);
            PlotBars(app,ax, app.cellID, NFields,  ["Cell ID", "# of fields"," "]);

            ax = nexttile(app.clusterTL);
            PeakR = GetAllPeakRate(app);
            PlotBars(app,ax, 1:length(PeakR), PeakR, ["Field ID", "Peak Rate"," "]);

            ax = nexttile(app.clusterTL);
            PlotBars(app,ax, 1:length(app.FieldSize), app.FieldSize, ["Field ID", "Field Size"," "]);

            if app.algorithm == "GMM"
                ax = nexttile(app.clusterTL);
                plot(ax,app.cellID, app.IsConverged, "k-")
                ylim(ax,[-0.2 1.2])
                yticks(ax,[0 1])
                yticklabels(ax,{"False", "True"})
                xlabel(ax,"Cell ID")
                ylabel(ax,"GMM convergence")
                box(ax,"off")
                axis(ax,"square")
            end
        end

        function PlotBars(app,ax, x, data, labels)
            bar(ax,x,data, "FaceColor","k")
            box(ax,"off")
            axis(ax,"square")
            xlabel(ax,labels(1))
            ylabel(ax,labels(2))
            title(ax,labels(3))
        end

        function peakRate = GetAllPeakRate(app)
            peakRate = [];
            for i = 1:app.N_cell
                peakRate = [peakRate; app.peakRate{i}];
            end

        end


        function PlotMaps(app, clusters)

            if ~isempty(clusters{app.current})
                [~,~, N_field] = size(clusters{app.current});
                fields= strings(N_field,1);
                for i =1:N_field
                    fields(i) = strcat("Field ", num2str(i));
                end
                app.FieldselectionListBox.Items = fields';
            else
                app.FieldselectionListBox.Items = {};
            end
            I = app.map{app.current};
            colLim = [min(I,[],"all") max(I,[],"all")] ;
            ax = nexttile(app.fieldTL);
            h= imagesc(ax,I);
            axis(ax,"square")
            set(h,"AlphaData", ~isnan(I))
            box(ax,"off")
            axis(ax,"off")
            clim(ax,[colLim(1) colLim(2)])
            colorbar(ax);
            title(ax,"Place Map")
            colormap(ax,app.ColormapDropDown.Value)

            if app.PlotpdfGMMonlyCheckBox.Value == 1 && ~isnan(app.IsConverged(app.current))
                [r,c] = size(I);
                x= 1:c;
                y = 1:r;
                gmPDF = @(x,y) arrayfun(@(x0,y0) pdf(app.gm{app.current},[x0 y0]),x,y);
                hold(ax,"on")
                fcontour(ax,gmPDF, "LineColor",[1 1 1])
            end


            if app.PlotSessionsCheckBox.Value == 1
                for i = 1:app.N_session
                    ax = nexttile(app.fieldTL);
                    h= imagesc(ax,app.map_sess{app.current,i});
                    axis(ax,"square")
                    set(h,"AlphaData", ~isnan(app.map_sess{app.current,i}))
                    box(ax,"off")
                    axis(ax,"off")
                    clim(ax,[colLim(1) colLim(2)])
                    colorbar(ax);
                    title(ax, strcat("Session ", num2str(i)))
                    colormap(ax,app.ColormapDropDown.Value)
                end

            end

            if ~isempty(clusters{app.current})
                app.field_ax = cell(N_field,1);
                for f = 1:N_field
                    app.field_ax{f} = nexttile(app.fieldTL);
                    h= imagesc(app.field_ax{f},I.*clusters{app.current}(:,:,f));
                    axis(app.field_ax{f},"square")
                    set(h,"AlphaData", ~isnan(I))
                    box(app.field_ax{f},"off")
                    axis(app.field_ax{f},"off")
                    clim(app.field_ax{f},[colLim(1) colLim(2)])
                    pr = round(max(I.*clusters{app.current}(:,:,f), [], "all"),2);
                    title(app.field_ax{f},strcat("Field ", num2str(f), " (", num2str(pr), ")"))
                    colormap(app.field_ax{f},app.ColormapDropDown.Value)
                end
            end

            if app.IsConverged(app.current)==1
                app.GMMconvergenceEditField_2.Value = "True";
            elseif app.IsConverged(app.current)==0
                app.GMMconvergenceEditField_2.Value = "False";
            else
                app.GMMconvergenceEditField_2.Value = "N/A";
            end


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            app.TextArea.Value = "";
            [file,app.location] = uigetfile('*.mat');
            load(strcat(app.location,file));
            str1 = strcat(app.location, file, " was loaded.");

            if exist("ActivityRate_full", "var")
                app.map = ActivityRate_full;
                app.N_cell = length(app.map);
                app.gm= cell(app.N_cell,1);
                str2 = num2str(app.N_cell) +" maps were found.";
                app.CellIDSpinner.Limits = [1 app.N_cell];

            else
                error("No data found!")
            end
            app.TextArea.Value = "";
            if exist("ActivityRate_sess", "var")
                app.map_sess = ActivityRate_sess;
                [~, app.N_session] =  size(app.map_sess);
                str3 = num2str(app.N_session)+ " sessions were found.";

            else
                str3 = "No session maps were found.";

            end

            if exist("cell_ID", "var")
                app.cellID = cell_ID;
                str4= "Cell ID list was found.";
            else
                app.cellID = 1:app.N_cell;
                str4=  "No Cell ID list was found.";
            end
            app.TextArea.Value = str1+newline+str2+newline+str3+newline+str4;


        end

        % Selection changed function: SelectAlgorithmButtonGroup
        function SelectAlgorithmButtonGroupSelectionChanged(app, event)
            app.algorithm = string(app.SelectAlgorithmButtonGroup.SelectedObject.Text);

        end

        % Button pushed function: RunclusteringButton
        function RunclusteringButtonPushed(app, event)
            rng(1); % for reproducibility
            app.minFieldRate = app.FieldCutoffEditField.Value;
            app.peakProm = app.MinpeakprominenceEditField.Value;
            app.maxIter = app.MaxiterationEditField.Value;
            app.clusters = cell(app.N_cell,1);
            app.peakRate =cell(app.N_cell,1);
            app.peakLoc =cell(app.N_cell,1);
            app.IsConverged = nan(app.N_cell,1);
            app.FieldSize = [];
            app.ClusteringstateLamp.Color = app.red;
            pause(0.5);
            for n= 1:app.N_cell
                [app.clusters{n}, app.peakRate{n}, app.peakLoc{n}, app.gm{n}] = WrapFieldClustering(app.map{n}, app.peakProm,app.minFieldRate, app.maxIter, app.algorithm);
                if app.algorithm == "GMM" & ~isempty(app.peakRate{n})
                    app.IsConverged(n) = app.gm{n}.Converged;
                    app.FieldWeight{n} = app.gm{n}.ComponentProportion;
                end
                app.FieldSize = [app.FieldSize; squeeze(sum(app.clusters{n},[1 2]))];
            end
            app.ClusteringstateLamp.Color = app.green;
            SummarizeClustering(app);

        end

        % Callback function
        function ShowResultsButtonPushed(app, event)

        end

        % Callback function: CellIDSpinner, PlotMapsButton
        function PlotMapsButtonPushed(app, event)
            app.fieldTL = tiledlayout(app.FieldViewerPanel,"flow");
            app.current = app.CellIDSpinner.Value;
            PlotMaps(app, app.clusters)

        end

        % Callback function
        function ViewFieldsTabButtonDown(app, event)
            app.fieldTL = tiledlayout(app.FieldViewerPanel,"flow");
        end

        % Button pushed function: RunclusteringButton_2
        function RunclusteringButton_2Pushed(app, event)
            rng(1);
            app.minFieldRate = app.FieldcutoffEditField.Value;
            app.peakProm = app.MinpeakprominenceEditField_2.Value;
            app.maxIter = app.MaxiterationEditField_2.Value;
            n = app.current;
            app.ClusteringstateLamp_2.Color = app.red;
            pause(0.5);
            [app.clusters{n}, app.peakRate{n}, app.peakLoc{n}, app.gm{n}] = WrapFieldClustering(app.map{n}, app.peakProm,app.minFieldRate, app.maxIter, app.algorithm);
            if app.algorithm == "GMM" & ~isempty(app.peakRate{n})
                app.IsConverged(n) = app.gm{n}.Converged;
            elseif app.algorithm == "K-means"
                app.IsConverged(n) = NaN;
            end
            app.ClusteringstateLamp_2.Color = app.green;
            PlotMapsButtonPushed(app, event)
        end

        % Selection changed function: SelectAlgorithmButtonGroup_2
        function SelectAlgorithmButtonGroup_2SelectionChanged(app, event)
            app.algorithm = string(app.SelectAlgorithmButtonGroup_2.SelectedObject.Text);

        end

        % Button pushed function: MergeButton
        function MergeButtonPushed(app, event)
           app.cluster_backup =  app.clusters;
           
           selected_fields = app.FieldselectionListBox.Value;
           if length(selected_fields) < 2
               msgbox("Select at least two fields!","Error");
           else
               %get field id
               selected_field_id = zeros(length(selected_fields),1); 
                for i = 1:length(selected_fields)
                    idx = find(isspace(selected_fields{i})==1);
                    selected_field_id(i) = str2num(selected_fields{i}(idx+1:end));
                end

                % merge fields
                for i = 2:length(selected_fields)
                    app.clusters{app.current}(:,:,selected_field_id(1)) = app.clusters{app.current}(:,:,selected_field_id(1)) + app.clusters{app.current}(:,:,selected_field_id(i));
                end
                %remove fields 
                app.clusters{app.current}(:,:,selected_field_id(2:end)) = [];
           

           % plot maps 
           app.fieldTL = tiledlayout(app.FieldViewerPanel,"flow");
           PlotMaps(app, app.clusters);
           end
           
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
           app.clusters  = app.cluster_backup;
           % plot maps 
           app.fieldTL = tiledlayout(app.FieldViewerPanel,"flow");
           PlotMaps(app, app.clusters);

        end

        % Button pushed function: SplitButton
        function SplitButtonPushed(app, event)
            selected_field = app.FieldselectionListBox.Value;
            
            if isempty(selected_field) 
                msgbox("Select a field!","Error");
            elseif length(selected_field) > 1
                msgbox("Select only one field!","Error");
            else
                app.cluster_backup =  app.clusters;
                idx = find(isspace(selected_field{1})==1);
                selected_field_id = str2double(selected_field{1}(idx+1:end));
                title(app.field_ax{selected_field_id}, "Split map!")
                roi = drawpolygon(app.field_ax{selected_field_id}, 'Color',[1 1 1]);
                xy = roi.Position;
                % split maps
                bw = roipoly(app.clusters{app.current}(:,:,selected_field_id), xy(:,1), xy(:,2));
                map1 = app.clusters{app.current}(:,:,selected_field_id).*~bw; % the remaining map
                map2 = app.clusters{app.current}(:,:,selected_field_id).*bw; % the isolated map
                
                
                [r,c,Nf] = size(app.clusters{app.current});
                app.clusters{app.current} = zeros(r,c,Nf+1);
                i = 1;
                k = 1;
                while i <= Nf
                    if i == selected_field_id 
                        app.clusters{app.current}(:,:,k) = map1;
                        app.clusters{app.current}(:,:,k+1) = map2;
                        k = k+2;
                        i = i+1;
                    else
                        app.clusters{app.current}(:,:,k) = app.cluster_backup{app.current}(:,:,i);
                        i = i+1;
                        k = k+1;
                    end

                end
                app.fieldTL = tiledlayout(app.FieldViewerPanel,"flow");
                PlotMaps(app, app.clusters);

            end

        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            %% save fields
            cellID = app.cellID;
            mask = app.clusters;
            save(strcat(app.location, "FieldClustering.mat"), "cellID","mask")
            msgbox(strcat("Saved as " , app.location, "FieldClustering.mat"), "Message")
        end

        % Button pushed function: RunanalysisButton
        function RunanalysisButtonPushed(app, event)
            %% apply each mask on place map - session by session
            sess_peakRate = [];
            sess_meanRate = [];
            field_ID = [];
            cell_ID = [];
            field_Size = [];
            f= 1;
            for i = 1:app.N_cell
                [~,~,Nfield] = size(app.mask{i}); 
                
                    for k = 1:Nfield
                        if ~isempty(app.mask{i})
                            field_ID(f) = f;
                            cell_ID(f) = app.cellID(i);
                            for j = 1:app.N_session
                                masked_map = app.map_sess{i,j}.*app.mask{i}(:,:,k);
                                sess_peakRate(f,j) = max(masked_map, [],"all", 'omitnan');
                                sess_meanRate(f,j) = mean(masked_map(masked_map>0),"all", 'omitnan');
                            end
                            field_Size(f) = length(masked_map(masked_map>0));
                            f = f+1;
                        end
                    end
            end
            field_ID = field_ID';
            cell_ID = cell_ID';
            field_Size = field_Size';
            Summary_tbl = table(field_ID, cell_ID, sess_meanRate, sess_peakRate, field_Size);
            save(strcat(app.location, "FieldClustering.mat"), "Summary_tbl", "-append");
            msgbox(strcat("Output file was updated!"), "Message")
        end

        % Button pushed function: DiscardButton
        function DiscardButtonPushed(app, event)
            app.cluster_backup = app.clusters;
            selected_fields = app.FieldselectionListBox.Value;
            if isempty(selected_fields)
                msgbox("Select at least one field!", "Error");
            
            else             %get field id
                selected_field_id = zeros(length(selected_fields),1); 
                for i = 1:length(selected_fields)
                    idx = find(isspace(selected_fields{i})==1);
                    selected_field_id(i) = str2num(selected_fields{i}(idx+1:end));
                end
                app.clusters{app.current}(:,:,selected_field_id) = [];
            end
           app.fieldTL = tiledlayout(app.FieldViewerPanel,"flow");
           PlotMaps(app, app.clusters);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ClusterFieldsTab = uitab(app.TabGroup);
            app.ClusterFieldsTab.Title = 'Cluster Fields';
            app.ClusteringResultsPanel = uipanel(app.ClusterFieldsTab);
            app.ClusteringResultsPanel.Title = 'Clustering Results';
            app.ClusteringPanel = uipanel(app.ClusterFieldsTab);
            app.ClusteringPanel.Title = 'Clustering';
            app.SelectAlgorithmButtonGroup = uibuttongroup(app.ClusteringPanel);
            app.SelectAlgorithmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectAlgorithmButtonGroupSelectionChanged, true);
            app.SelectAlgorithmButtonGroup.Title = 'Select Algorithm';
            app.GMMButton = uiradiobutton(app.SelectAlgorithmButtonGroup);
            app.GMMButton.Text = 'GMM';
            app.GMMButton.Value = true;
            app.KmeansButton = uiradiobutton(app.SelectAlgorithmButtonGroup);
            app.KmeansButton.Text = 'K-means';
            app.MinpeakprominenceEditFieldLabel = uilabel(app.ClusteringPanel);
            app.MinpeakprominenceEditFieldLabel.Text = 'Min. peak prominence ';
            app.MinpeakprominenceEditField = uieditfield(app.ClusteringPanel, 'numeric');
            app.MinpeakprominenceEditField.Limits = [0 Inf];
            app.MinpeakprominenceEditField.Value = 2;
            app.FieldCutoffEditFieldLabel = uilabel(app.ClusteringPanel);
            app.FieldCutoffEditFieldLabel.Text = 'Field Cut-off';
            app.FieldCutoffEditField = uieditfield(app.ClusteringPanel, 'numeric');
            app.FieldCutoffEditField.Limits = [0 Inf];
            app.FieldCutoffEditField.Value = 0.2;
            app.MaxiterationEditFieldLabel = uilabel(app.ClusteringPanel);
            app.MaxiterationEditFieldLabel.Text = 'Max. iteration';
            app.MaxiterationEditField = uieditfield(app.ClusteringPanel, 'numeric');
            app.MaxiterationEditField.Limits = [1 Inf];
            app.MaxiterationEditField.Value = 100;
            app.RunclusteringButton = uibutton(app.ClusteringPanel, 'push');
            app.RunclusteringButton.ButtonPushedFcn = createCallbackFcn(app, @RunclusteringButtonPushed, true);
            app.RunclusteringButton.Text = 'Run clustering';
            app.ClusteringstateLamp = uilamp(app.ClusteringPanel);
            app.LoadingdataPanel = uipanel(app.ClusterFieldsTab);
            app.LoadingdataPanel.Title = 'Loading data';
            app.LoadDataButton = uibutton(app.LoadingdataPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.TextArea = uitextarea(app.LoadingdataPanel);
            app.ViewFieldsTab = uitab(app.TabGroup);
            app.ViewFieldsTab.Title = 'View Fields';
            app.ClusteringPanel_2 = uipanel(app.ViewFieldsTab);
            app.ClusteringPanel_2.Title = 'Clustering';
            app.SelectAlgorithmButtonGroup_2 = uibuttongroup(app.ClusteringPanel_2);
            app.SelectAlgorithmButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @SelectAlgorithmButtonGroup_2SelectionChanged, true);
            app.SelectAlgorithmButtonGroup_2.Title = 'Select Algorithm';
            app.GMMButton_2 = uiradiobutton(app.SelectAlgorithmButtonGroup_2);
            app.GMMButton_2.Text = 'GMM';
            app.GMMButton_2.Value = true;
            app.KmeansButton_2 = uiradiobutton(app.SelectAlgorithmButtonGroup_2);
            app.KmeansButton_2.Text = 'K-means';
            app.MinpeakprominenceEditField_2Label = uilabel(app.ClusteringPanel_2);
            app.MinpeakprominenceEditField_2Label.Text = 'Min. peak prominence ';
            app.MinpeakprominenceEditField_2 = uieditfield(app.ClusteringPanel_2, 'numeric');
            app.MinpeakprominenceEditField_2.Limits = [0 Inf];
            app.MinpeakprominenceEditField_2.Value = 2;
            app.FieldcutoffEditFieldLabel = uilabel(app.ClusteringPanel_2);
            app.FieldcutoffEditFieldLabel.Text = 'Field cut-off';
            app.FieldcutoffEditField = uieditfield(app.ClusteringPanel_2, 'numeric');
            app.FieldcutoffEditField.Limits = [0 Inf];
            app.FieldcutoffEditField.Value = 0.2;
            app.MaxiterationEditField_2Label = uilabel(app.ClusteringPanel_2);
            app.MaxiterationEditField_2Label.Text = 'Max. iteration';
            app.MaxiterationEditField_2 = uieditfield(app.ClusteringPanel_2, 'numeric');
            app.MaxiterationEditField_2.Limits = [1 Inf];
            app.MaxiterationEditField_2.Value = 100;
            app.RunclusteringButton_2 = uibutton(app.ClusteringPanel_2, 'push');
            app.RunclusteringButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunclusteringButton_2Pushed, true);
            app.RunclusteringButton_2.Text = 'Run clustering';
            app.ClusteringstateLamp_2 = uilamp(app.ClusteringPanel_2);
            app.GMMconvergenceEditFieldLabel_2 = uilabel(app.ClusteringPanel_2);
            app.GMMconvergenceEditFieldLabel_2.Text = 'GMM convergence';
            app.GMMconvergenceEditField_2 = uieditfield(app.ClusteringPanel_2, 'text');
            app.GMMconvergenceEditField_2.Editable = 'off';
            app.ManualeditingPanel = uipanel(app.ViewFieldsTab);
            app.ManualeditingPanel.Title = 'Manual editing';
            app.FieldselectionListBoxLabel = uilabel(app.ManualeditingPanel);
            app.FieldselectionListBoxLabel.Text = 'Field selection';
            app.FieldselectionListBox = uilistbox(app.ManualeditingPanel);
            app.FieldselectionListBox.Items = {''};
            app.FieldselectionListBox.Multiselect = 'on';
            app.FieldselectionListBox.Value = {};
            app.UndoButton = uibutton(app.ManualeditingPanel, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.MergeButton = uibutton(app.ManualeditingPanel, 'push');
            app.MergeButton.ButtonPushedFcn = createCallbackFcn(app, @MergeButtonPushed, true);
            app.MergeButton.Text = 'Merge';
            app.SplitButton = uibutton(app.ManualeditingPanel, 'push');
            app.SplitButton.ButtonPushedFcn = createCallbackFcn(app, @SplitButtonPushed, true);
            app.SplitButton.Text = 'Split';
            app.DiscardButton = uibutton(app.ManualeditingPanel, 'push');
            app.DiscardButton.ButtonPushedFcn = createCallbackFcn(app, @DiscardButtonPushed, true);
            app.DiscardButton.Text = 'Discard';
            app.PlottingPanel = uipanel(app.ViewFieldsTab);
            app.PlottingPanel.Title = 'Plotting';
            app.PlotSessionsCheckBox = uicheckbox(app.PlottingPanel);
            app.PlotSessionsCheckBox.Text = 'Plot Sessions';
            app.PlotpdfGMMonlyCheckBox = uicheckbox(app.PlottingPanel);
            app.PlotpdfGMMonlyCheckBox.Text = 'Plot pdf (GMM only)';
            app.CellIDSpinnerLabel = uilabel(app.PlottingPanel);
            app.CellIDSpinnerLabel.Text = 'Cell ID';
            app.CellIDSpinner = uispinner(app.PlottingPanel);
            app.CellIDSpinner.Limits = [1 Inf];
            app.CellIDSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotMapsButtonPushed, true);
            app.CellIDSpinner.Value = 1;
            app.PlotMapsButton = uibutton(app.PlottingPanel, 'push');
            app.PlotMapsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMapsButtonPushed, true);
            app.PlotMapsButton.Text = 'Plot Maps';
            app.ColormapDropDownLabel = uilabel(app.PlottingPanel);
            app.ColormapDropDownLabel.Text = 'Color map';
            app.ColormapDropDown = uidropdown(app.PlottingPanel);
            app.ColormapDropDown.Items = {'jet', 'parula', 'summer', 'gray', 'bone', 'copper', 'pink', 'sky', 'abyss', 'turbo', 'cool', 'spring', 'summer', 'hot'};
            app.ColormapDropDown.Value = 'jet';
            app.FieldViewerPanel = uipanel(app.ViewFieldsTab);
            app.FieldViewerPanel.Title = 'Field Viewer';
            app.SaveResultsButton = uibutton(app.ViewFieldsTab, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.AnalyzeFieldsTab = uitab(app.TabGroup);
            app.AnalyzeFieldsTab.Title = 'Analyze Fields';
            app.AnalysisoptionsPanel = uipanel(app.AnalyzeFieldsTab);
            app.AnalysisoptionsPanel.Title = 'Analysis options';
            app.ListBox = uilistbox(app.AnalysisoptionsPanel);
            app.ListBox.Items = {'Rate analysis'};
            app.ListBox.Value = 'Rate analysis';
            app.RunanalysisButton = uibutton(app.AnalysisoptionsPanel, 'push');
            app.RunanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButtonPushed, true);
            app.RunanalysisButton.Text = 'Run analysis';
            app.AnalysisViewerPanel = uipanel(app.AnalyzeFieldsTab);
            app.AnalysisViewerPanel.Title = 'Analysis Viewer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FieldClusterApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end