classdef DriftAnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ManuallabelingPanel             matlab.ui.container.Panel
        OkButton                        matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        SaveResultsButton               matlab.ui.control.Button
        SaveexamplesCheckBox            matlab.ui.control.CheckBox
        CategorizeButtonGroup           matlab.ui.container.ButtonGroup
        ThresholdneededButton           matlab.ui.control.RadioButton
        NondriftingButton               matlab.ui.control.RadioButton
        DriftingButton                  matlab.ui.control.RadioButton
        CellSpinner                     matlab.ui.control.Spinner
        CellSpinnerLabel                matlab.ui.control.Label
        ShowDropDown                    matlab.ui.control.DropDown
        ShowDropDownLabel               matlab.ui.control.Label
        AutomatizedlabelingPanel        matlab.ui.container.Panel
        LabelcellsButton                matlab.ui.control.Button
        ofexamplesEditFieldLabel        matlab.ui.control.Label
        ofexamplesEditField             matlab.ui.control.NumericEditField
        DriftingsegmentsEditField       matlab.ui.control.NumericEditField
        DriftingsegmentsEditFieldLabel  matlab.ui.control.Label
        DriftingthresholdEditField      matlab.ui.control.NumericEditField
        DriftingthresholdEditFieldLabel  matlab.ui.control.Label
        SegmentlengthminEditField       matlab.ui.control.NumericEditField
        SegmentlengthminEditFieldLabel  matlab.ui.control.Label
        MSframerateEditField            matlab.ui.control.NumericEditField
        MSframerateEditFieldLabel       matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        workdir; % folder to work with
        c % calcium trace
        s % deconvolved signal
        Mins; % minimum value for each segment for each cell
        IsDrifting% binary vector whether it's classified as 0 or 1
        current_cell; % index of current cell
        ToPlot; % index of cells to plot (drifting or non-drifting)
    end
    
    methods (Access = public)
        
        function PlotTraces(app)
            hold(app.UIAxes, "off")
            plot(app.UIAxes,app.c(:,app.ToPlot(app.CellSpinner.Value)), "k-")
            hold(app.UIAxes, "on")
            plot(app.UIAxes,app.s(:,app.ToPlot(app.CellSpinner.Value)), "r-")
            box(app.UIAxes, "off")
            title(app.UIAxes, strcat("Cell ", num2str(app.ToPlot(app.CellSpinner.Value))))
            xlabel(app.UIAxes,"Frames")
            ylabel(app.UIAxes,"A.U.")
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            app.workdir = uigetdir;
            f = waitbar(0,'Please wait...');
            pause(.5)
            
            waitbar(.5,f,'Loading your data');
              
            [app.c,app.s,~,~] = ReadNCData(app.workdir);
            waitbar(1,f,'Loading your data');
            close(f)

        end

        % Button pushed function: LabelcellsButton
        function LabelcellsButtonPushed(app, event)
            DriftParams.Thresh = app.DriftingthresholdEditField.Value;
            DriftParams.Dur = app.DriftingsegmentsEditField.Value;
            [app.Mins, app.IsDrifting] = DetectDrift(app.c, app.SegmentlengthminEditField.Value, app.MSframerateEditField.Value, DriftParams);
           
            type = app.ShowDropDown.Value;

            if type == "Drifting"
                app.ToPlot =find(app.IsDrifting == 1);
            else
                app.ToPlot = find(app.IsDrifting == 0);
            end

            app.CellSpinner.Limits = [1 length(app.ToPlot)];   
            msgbox("Labelling is finished!", 'Message')
            PlotTraces(app);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
             PlotTraces(app);
             app.CellSpinner.Limits = [1 length(app.ToPlot)]; 
        end

        % Value changed function: CellSpinner
        function CellSpinnerValueChanged(app, event)
            %% set radio button to the currently shown type
            switch app.ShowDropDown.Value
                case "Drifting"
                     app.DriftingButton.Value  =1;
                case "Non-drifting"
                     app.NondriftingButton.Value = 1;
            end
            PlotTraces(app);
            app.CellSpinner.Limits = [1 length(app.ToPlot)]; 
            
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
           idx = app.CellSpinner.Value;
           type = app.CategorizeButtonGroup.SelectedObject.Text;
           if type == "Drifting"
                app.IsDrifting(app.ToPlot(idx))  =  1;
           elseif type == "Non-drifting"
                app.IsDrifting(app.ToPlot(idx))  =  0;
           else
               app.IsDrifting(app.ToPlot(idx))  =  2;
           end
           title(app.UIAxes, strcat("Label changed succesfully to ", type))
           
        end

        % Value changed function: ShowDropDown
        function ShowDropDownValueChanged(app, event)
            if app.ShowDropDown.Value == "Drifting"
                app.ToPlot = find(app.IsDrifting == 1);

           else
               app.ToPlot = find(app.IsDrifting == 0);
           end
            
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            N_example = 50;
            f = waitbar(0,'Please wait...');
            pause(.2)
            out = strcat(app.workdir, "\processedData\");

            if ~exist('out', 'dir')
                mkdir(out);
            end

            labels = ["Non-drift", "Drift", "Threshold needed"];
            IsDrifting = app.IsDrifting;
            save(strcat(out,"\DriftDetection.mat"), "IsDrifting", "labels");

            if app.SaveexamplesCheckBox.Value == 1
                
                for i  = 0:2
                    waitbar(.25+0.25*i,f,'Saving examples...');
                    file_name = strcat(out, labels(i+1), ".pdf");
                    if exist(file_name, "file")

                        delete(file_name)
                    end
                    idx = find(app.IsDrifting == i);
                    if N_example < length(idx)
                        y = datasample(idx, N_example);
                    else
                        y = idx; % if there are less cells than the requested # of examples, plot all the cells
                    end
                
                    for j = 1:length(y)
                        figure("Visible","off")
                        plot(app.c(:,y(j)), "k-")
                        hold on
                        plot(app.s(:,y(j)), "r-")
                        box off
                        title(strcat("ID ", num2str(y(j))))
                        exportgraphics(gca, file_name, "Append", true )
                        close(gcf)
                    end
                end
            end
            waitbar(1,f,'Saving has finished');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AutomatizedlabelingPanel = uipanel(app.UIFigure);
            app.AutomatizedlabelingPanel.Title = 'Automatized labeling';
            app.LoadDataButton = uibutton(app.AutomatizedlabelingPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.MSframerateEditFieldLabel = uilabel(app.AutomatizedlabelingPanel);
            app.MSframerateEditFieldLabel.Text = 'MS framerate';
            app.MSframerateEditField = uieditfield(app.AutomatizedlabelingPanel, 'numeric');
            app.MSframerateEditField.Limits = [0 Inf];
            app.MSframerateEditField.Value = 20;
            app.SegmentlengthminEditFieldLabel = uilabel(app.AutomatizedlabelingPanel);
            app.SegmentlengthminEditFieldLabel.Text = 'Segment length (min)';
            app.SegmentlengthminEditField = uieditfield(app.AutomatizedlabelingPanel, 'numeric');
            app.SegmentlengthminEditField.Value = 1;
            app.DriftingthresholdEditFieldLabel = uilabel(app.AutomatizedlabelingPanel);
            app.DriftingthresholdEditFieldLabel.Text = 'Drifting threshold';
            app.DriftingthresholdEditField = uieditfield(app.AutomatizedlabelingPanel, 'numeric');
            app.DriftingthresholdEditField.Value = 0.1;
            app.DriftingsegmentsEditFieldLabel = uilabel(app.AutomatizedlabelingPanel);
            app.DriftingsegmentsEditFieldLabel.Text = 'Drifting (#segments)';
            app.DriftingsegmentsEditField = uieditfield(app.AutomatizedlabelingPanel, 'numeric');
            app.DriftingsegmentsEditField.Value = 5;
            app.ofexamplesEditField = uieditfield(app.AutomatizedlabelingPanel, 'numeric');
            app.ofexamplesEditFieldLabel = uilabel(app.AutomatizedlabelingPanel);
            app.ofexamplesEditFieldLabel.Text = '# of examples';
            app.LabelcellsButton = uibutton(app.AutomatizedlabelingPanel, 'push');
            app.LabelcellsButton.ButtonPushedFcn = createCallbackFcn(app, @LabelcellsButtonPushed, true);
            app.LabelcellsButton.Text = 'Label cells';
            app.ManuallabelingPanel = uipanel(app.UIFigure);
            app.ManuallabelingPanel.Title = 'Manual labeling';
            app.ShowDropDownLabel = uilabel(app.ManuallabelingPanel);
            app.ShowDropDownLabel.Text = 'Show ';
            app.ShowDropDown = uidropdown(app.ManuallabelingPanel);
            app.ShowDropDown.Items = {'Drifting', 'Non-drifting'};
            app.ShowDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowDropDownValueChanged, true);
            app.ShowDropDown.Value = 'Drifting';
            app.CellSpinnerLabel = uilabel(app.ManuallabelingPanel);
            app.CellSpinnerLabel.Text = 'Cell';
            app.CellSpinner = uispinner(app.ManuallabelingPanel);
            app.CellSpinner.Limits = [1 Inf];
            app.CellSpinner.ValueChangedFcn = createCallbackFcn(app, @CellSpinnerValueChanged, true);
            app.CellSpinner.Value = 1;
            app.CategorizeButtonGroup = uibuttongroup(app.ManuallabelingPanel);
            app.CategorizeButtonGroup.Title = 'Categorize';
            app.DriftingButton = uiradiobutton(app.CategorizeButtonGroup);
            app.DriftingButton.Text = 'Drifting';
            app.DriftingButton.Value = true;
            app.NondriftingButton = uiradiobutton(app.CategorizeButtonGroup);
            app.NondriftingButton.Text = 'Non-drifting';
            app.ThresholdneededButton = uiradiobutton(app.CategorizeButtonGroup);
            app.ThresholdneededButton.Text = 'Threshold needed';
            app.SaveexamplesCheckBox = uicheckbox(app.ManuallabelingPanel);
            app.SaveexamplesCheckBox.Text = 'Save examples';
            app.SaveexamplesCheckBox.Value = true;
            app.SaveResultsButton = uibutton(app.ManuallabelingPanel, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.PlotButton = uibutton(app.ManuallabelingPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.OkButton = uibutton(app.ManuallabelingPanel, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DriftAnalysisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end